classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        Button            matlab.ui.control.Button
        ODEDropDown       matlab.ui.control.DropDown
        ODEDropDownLabel  matlab.ui.control.Label
        Slider2           matlab.ui.control.Slider
        Slider2Label      matlab.ui.control.Label
        Slider            matlab.ui.control.Slider
        SliderLabel       matlab.ui.control.Label
        RightPanel        matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
    
        function [fun, t_span, y0] = getSelectedFunction(app)
            % Legge la funzione selezionata dal dropdown
            fun_selected = app.ODEDropDown.Value;
            index = find(strcmp(app.ODEDropDown.Items, fun_selected));  % indice numerico
    
            % Parametri
            T = [20,20,2];
            y0s = {[1.5;3], 1, 5};
            f1 = @(t,y) (3 + cos(t) - y);
            f2 = @(t,y) (y.*(2+y).*(2-y));
            functions = {@brusselator, f1, f2};
    
            % Seleziona funzione e parametri
            fun = functions{index};
            t_span = [0, T(index)];
            y0 = y0s{index};
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cla(app.UIAxes) 
            
            % Parametri per il controllo del passo
            atol = 1e-4;
            rtol = 1e-4;
            p_embedded = 4; % Ordine del metodo embedded (4(3))

            [fun, t_span, y0] = app.getSelectedFunction();
            x0 = t_span(1);

            % Calcolo del passo iniziale suggerito
            h0 = initial_step_selector(fun, x0, y0, p_embedded, atol, rtol);

            t_current = x0;
            y_current = y0;
            h = h0; % Usa il passo iniziale calcolato
            k1 = []; % Per FSAL, inizialmente vuoto
            
            % Array per salvare i risultati
            t_embedded = t_current;
            y_embedded = y_current;
            
            % Parametri per il controllo del passo
            q = 3; % Ordine dell'errore per il metodo 4(3)
            fac = 0.8;
            facmin = 0.1;
            facmax = 5.0;
            max_steps = 5000;
            steps_taken = 0;
            rejected_steps = 0;
            
            % Inizializza vettori di log prima del loop
            h_history = [];
            t_history = [];
            is_accepted = []; % 1 per accettato, 0 per rifiutato
            
            while t_current < t_span(2) && steps_taken < max_steps
                steps_taken = steps_taken + 1;
                
                if t_current + h > t_span(2)
                    h_tentativo = t_span(2) - t_current; 
                else
                    h_tentativo = h;
                end
                
                [y_high, ~, k_next, err] = embedded_solver(fun, t_current, y_current, h_tentativo, k1, atol, rtol);
            
                % --- LOG DI OGNI TENTATIVO ---
                t_history(end+1) = t_current;
                h_history(end+1) = h_tentativo;
            
                if err < 1
                    % PASSO ACCETTATO
                    is_accepted(end+1) = 1;
                    
                    t_current = t_current + h_tentativo;
                    y_current = y_high;
                    k1 = k_next;
                    
                    t_embedded(:, end+1) = t_current;
                    y_embedded(:, end+1) = y_current;
                    facmax_current = facmax;
                else
                    % PASSO RIFIUTATO
                    is_accepted(end+1) = 0;
                    
                    rejected_steps = rejected_steps + 1;
                    facmax_current = 1;
                end
                
                h = step_control(h_tentativo, err, q, fac, facmin, facmax_current);
            end
                
            % Separiamo i dati per il plot
            t_acc = t_history(is_accepted == 1);
            h_acc = h_history(is_accepted == 1);
            
            t_rej = t_history(is_accepted == 0);
            h_rej = h_history(is_accepted == 0);
            
            % Plot in scala semilogaritmica
            semilogy(app.UIAxes,t_acc, h_acc, 'b.-', 'LineWidth', 1, 'MarkerSize', 10); 
            hold(app.UIAxes,'on')
            semilogy(app.UIAxes,t_rej, h_rej, 'rx', 'LineWidth', 1.5, 'MarkerSize', 8);
            
            % Aggiunta estetica: limite superiore/inferiore
            grid(app.UIAxes,'on')                   % griglia nell'UIAxes
            y_lims = ylim(app.UIAxes);              % leggi limiti dal UIAxes
            ylim(app.UIAxes, [y_lims(1)*0.5, y_lims(2)*1.5]);  % aggiorna limiti
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [1e-05 0.1];
            app.Slider.Value = 1e-05;
            app.Slider2Label = uilabel(app.LeftPanel);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.LeftPanel);
            app.ODEDropDownLabel = uilabel(app.LeftPanel);
            app.ODEDropDownLabel.Text = 'ODE';
            app.ODEDropDown = uidropdown(app.LeftPanel);
            app.ODEDropDown.Items = {'Brusselator', 'y''=3+cos(t)-y', 'y''=y(2+y)(2-y)'};
            app.ODEDropDown.Value = 'Brusselator';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end